classdef TESTAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        RoMMeasurementTab               matlab.ui.container.Tab
        ExtRotImg                       matlab.ui.control.Image
        AbFlexImg                       matlab.ui.control.Image
        CalibArmLabel                   matlab.ui.control.Label
        CalibratingLabel                matlab.ui.control.Label
        SAVEButton                      matlab.ui.control.Button
        IncForm                         matlab.ui.control.Label
        IncorrectFormDetectedEditField  matlab.ui.control.EditField
        IncorrectFormDetectedEditFieldLabel  matlab.ui.control.Label
        OverComp                        matlab.ui.control.Label
        OvercompensationDetectedEditField  matlab.ui.control.EditField
        OvercompensationDetectedLabel   matlab.ui.control.Label
        MaxAngleEditField               matlab.ui.control.EditField
        MaxAngleEditFieldLabel          matlab.ui.control.Label
        CurrentAngleEditField           matlab.ui.control.EditField
        CurrentAngleEditFieldLabel      matlab.ui.control.Label
        StartExerciseButton             matlab.ui.control.Button
        resetmeasurementButton          matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        SelectanExerciseDropDown        matlab.ui.control.DropDown
        SubRightAxes                    matlab.ui.control.UIAxes
        SubLeftAxes                     matlab.ui.control.UIAxes
        MainAxes                        matlab.ui.control.UIAxes
        ViewPreviousExercisesTab        matlab.ui.container.Tab
        MaxAngleEditField_2             matlab.ui.control.NumericEditField
        MaxAngleEditField_2Label        matlab.ui.control.Label
        IncorrectFormDetectedEditField_2  matlab.ui.control.EditField
        IncorrectFormDetectedLabel      matlab.ui.control.Label
        OvercompensationDetectedEditField_2  matlab.ui.control.EditField
        OvercompensationDetectedEditField_2Label  matlab.ui.control.Label
        Image_2                         matlab.ui.control.Image
        ExternalRotationListBox         matlab.ui.control.ListBox
        ExternalRotationListBoxLabel    matlab.ui.control.Label
        FlexionListBox                  matlab.ui.control.ListBox
        FlexionListBoxLabel             matlab.ui.control.Label
        AbductionListBox                matlab.ui.control.ListBox
        AbductionListBoxLabel           matlab.ui.control.Label
        SubRightAxes2                   matlab.ui.control.UIAxes
        SubLeftAxes2                    matlab.ui.control.UIAxes
        MainAxes2                       matlab.ui.control.UIAxes
        HelpTab                         matlab.ui.container.Tab
    end

  %%TODO 
  %viewing multiple saved exercises
  %update exercise example pictures
  %testing


    properties (Access = public)
        arduinoObj % Description
        MainPlot
        SubPlotLeft
        SubPlotRight
        MeasureInProg = 0 %Measurement in progress
        fileID
        fileID2
        fileName0
        fileName1
        ExerciseType
        DateTimeString
        internal_date_time_done
    end

    methods (Access = public)

        function savedata(app)
            disp(convertStringsToChars(app.DateTimeString))
            if(app.ExerciseType == "Abduction")
                app.AbductionListBox.Items(end+1) = {convertStringsToChars(app.DateTimeString)};
                app.AbductionListBox.ItemsData(end+1) = app.internal_date_time_done;
            elseif (app.ExerciseType == "Flexion")
                app.FlexionListBox.Items(end+1) = {convertStringsToChars(app.DateTimeString)};
                app.FlexionListBox.ItemsData(end+1) = app.internal_date_time_done;
            else
                app.ExternalRotationListBox.Items(end+1) = {convertStringsToChars(app.DateTimeString)};
                app.ExternalRotationListBox.ItemsData(end+1) = app.internal_date_time_done;
            end
        end

        function cleardata(app)
            app.arduinoObj.UserData.RollPlot = plot(app.MainAxes,NaN,NaN); %TODO: ensure this doesnt change plotting locations
            app.arduinoObj.UserData.PitchPlot = plot(app.SubLeftAxes,NaN,NaN);
            app.arduinoObj.UserData.YawPlot = plot(app.SubRightAxes,NaN,NaN);
            app.CurrentAngleEditField.Value = string(0);
            app.MaxAngleEditField.Value = string(0);
            app.arduinoObj.UserData.Roll(:) = 0;
            app.arduinoObj.UserData.Pitch(:) = 0;
            app.arduinoObj.UserData.Yaw(:) = 0;
        end

        function readIMUData(app,src, ~)
            if(~app.MeasureInProg || toc > 120) 
                configureCallback(src, "off");
                write(app.arduinoObj,'d',"char"); %stop exercise
                fclose(app.fileID);
                fclose(app.fileID2);
                if(toc>120)
                    savedata(app);
                end
                return;
            end

            % Read the ASCII data from the serialport object.
            line = readline(src);

            %extract sensor data
            IMUdata = sscanf(line, '%f,%f,%f,%f');

            if (~isempty(IMUdata))

                if (IMUdata(1)==1) %wrist
                    Roll = IMUdata(2);
                    Pitch = IMUdata(3);
                    Yaw = IMUdata(4);

                    %solution for -180 to 180 discontinuity
                    %for efficiency, can check for normal case (current angle and previous one have same sign)
                    %first to skip the other comparisons
                    if (src.UserData.Count1 > 1)
                        if (abs(Roll) > 45 && sign(Roll * src.UserData.Roll(src.UserData.Count1-1)) < 0)
                            Roll = Roll + sign(src.UserData.Roll(src.UserData.Count1-1))*360;
                        end
                        if (abs(Pitch) > 45 && sign(Pitch * src.UserData.Pitch(src.UserData.Count1-1)) < 0)
                            Pitch = Pitch + sign(src.UserData.Pitch(src.UserData.Count1-1))*360;
                        end
                        if (abs(Yaw) > 45 && sign(Yaw * src.UserData.Yaw(src.UserData.Count1-1)) < 0)
                            Yaw = Yaw + sign(src.UserData.Yaw(src.UserData.Count1-1))*360;
                        end
                    end

                    src.UserData.Roll(src.UserData.Count1) = Roll;
                    src.UserData.Pitch(src.UserData.Count1) = Pitch;
                    src.UserData.Yaw(src.UserData.Count1) = Yaw;

                    src.UserData.Count1 = src.UserData.Count1 + 1;

                    elapsedTime = toc;

                    %app.curr_angle.Text = string(Roll);                    
                    %app.max_angle.Text = string(max(src.UserData.Roll));
                    if(app.ExerciseType == "Abduction" || app.ExerciseType == "Flexion")
                        app.MaxAngleEditField.Value = string(max(src.UserData.Roll));
                        app.CurrentAngleEditField.Value = string(Roll);
                    else %external rotation
                        app.MaxAngleEditField.Value = string(max(src.UserData.Yaw));
                        app.CurrentAngleEditField.Value = string(Yaw);                      
                    end

                    %write data to file
                    fprintf(app.fileID,'%.2f,%s,%s\n',[elapsedTime,line,app.MaxAngleEditField.Value]);

                    %update plot data by adding on the new points
                    xdata = get(src.UserData.RollPlot,'XData');
                    ydata = get(src.UserData.RollPlot,'YData');
                    set(src.UserData.RollPlot,'XData',[xdata elapsedTime], 'YData',[ydata Roll],"Color","blue");


                    xdata2 = get(src.UserData.PitchPlot,'XData');
                    ydata2 = get(src.UserData.PitchPlot,'YData');
                    set(src.UserData.PitchPlot,'XData',[xdata2 elapsedTime], 'YData',[ydata2 Pitch],"Color","blue");

                    xdata3 = get(src.UserData.YawPlot,'XData');
                    ydata3 = get(src.UserData.YawPlot,'YData');
                    set(src.UserData.YawPlot,'XData',[xdata3 elapsedTime], 'YData',[ydata3 Yaw],"Color","blue");

                elseif (IMUdata(1)==0) %shoulder 

                   Roll2 = IMUdata(2);
                    Pitch2 = IMUdata(3);
                    Yaw2 = IMUdata(4);

                    if (abs(Roll2) > 45 && sign(Roll2 * src.UserData.Roll2(src.UserData.Count2-1)) < 0)
                        Roll2 = Roll2 + sign(src.UserData.Roll2(src.UserData.Count2-1))*360;
                    end
                    if (abs(Pitch2) > 45 && sign(Pitch2 * src.UserData.Pitch2(src.UserData.Count2-1)) < 0)
                        Pitch2 = Pitch2 + sign(src.UserData.Pitch2(src.UserData.Count2-1))*360;
                    end
                    if (abs(Yaw2) > 45 && sign(Yaw2 * src.UserData.Yaw2(src.UserData.Count2-1)) < 0)
                        Yaw2 = Yaw2 + sign(src.UserData.Yaw2(src.UserData.Count2-1))*360;
                    end

                    src.UserData.Roll2(src.UserData.Count2) = Roll2;
                    src.UserData.Pitch2(src.UserData.Count2) = Pitch2;
                    src.UserData.Yaw2(src.UserData.Count2) = Yaw2;

                    % Update the Count value of the serialport object.
                    src.UserData.Count2 = src.UserData.Count2 + 1;

                    elapsedTime = toc;

                    fprintf(app.fileID2,'%.2f,%s,%s\n',[elapsedTime,line,string(max(src.UserData.Roll2))]);
% 
%                     xdata = get(src.UserData.PlotObj1_5,'XData');
%                     ydata = get(src.UserData.PlotObj1_5,'YData');
%                     set(src.UserData.PlotObj1_5,'XData',[xdata elapsedTime], 'YData',[ydata Roll2],"Color","red");
% 
%                     xdata2 = get(src.UserData.PlotObj2_5,'XData');
%                     ydata2 = get(src.UserData.PlotObj2_5,'YData');
%                     set(src.UserData.PlotObj2_5,'XData',[xdata2 elapsedTime], 'YData',[ydata2 Pitch2],"Color","red");
% 
%                     xdata3 = get(src.UserData.PlotObj3_5,'XData');
%                     ydata3 = get(src.UserData.PlotObj3_5,'YData');
%                     set(src.UserData.PlotObj3_5,'XData',[xdata3 elapsedTime], 'YData',[ydata3 Yaw2],"Color","red");
                end

            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %initialization
            %clear; clc;
            
            app.MainAxes.YLim = [-180, 180];
            app.MainAxes.YTick = [-180, -135, -90, -45, 0, 45, 90, 135, 180];
            app.MainAxes.XLim = [0, 120];
            app.MainAxes.XTick = [0,15,30,45,60,75,90,105,120];
            app.SubLeftAxes.YLim = [-180, 180];
            app.SubLeftAxes.YTick = [-180, -135, -90, -45, 0, 45, 90, 135, 180];
            app.SubLeftAxes.XLim = [0, 120];
            app.SubLeftAxes.XTick = [0,15,30,45,60,75,90,105,120];
            app.SubRightAxes.YLim = [-180, 180];
            app.SubRightAxes.YTick = [-180, -135, -90, -45, 0, 45, 90, 135, 180];
            app.SubRightAxes.XLim = [0, 120];
            app.SubRightAxes.XTick = [0,15,30,45,60,75,90,105,120];

            app.arduinoObj = serialport("COM6", 115200);
            configureTerminator(app.arduinoObj,"CR/LF","CR/LF");
            flush(app.arduinoObj);
            app.arduinoObj.UserData = struct("Roll",[],"Pitch",[],"Yaw",[],"Extra",[],"Count1",1, "Count2",1);
            app.arduinoObj.Timeout = 60;
            
            %Pre allocate sizes for speed improvements
            app.arduinoObj.UserData.Roll = zeros(1,1000000); %TODO CALCULATE PROPER SIZE
            app.arduinoObj.UserData.Pitch = zeros(1,1000000); %TODO CALCULATE PROPER SIZE
            app.arduinoObj.UserData.Yaw = zeros(1,1000000); %TODO CALCULATE PROPER SIZE

            app.arduinoObj.UserData.Roll2 = zeros(1,1000000); %TODO CALCULATE PROPER SIZE
            app.arduinoObj.UserData.Pitch2 = zeros(1,1000000); %TODO CALCULATE PROPER SIZE
            app.arduinoObj.UserData.Yaw2 = zeros(1,1000000); %TODO CALCULATE PROPER SIZE

            app.SAVEButton.Enable = 0;


            disp(readline(app.arduinoObj)); %Press any key to continue...
            write(app.arduinoObj,'r',"char"); %key pressed
            disp(readline(app.arduinoObj)); %Initialization of the sensor returned: All is well.
            disp(readline(app.arduinoObj)); %All is well.
            disp(readline(app.arduinoObj)); %Device connected!
            disp(readline(app.arduinoObj)); %DMP enabled!
            disp(readline(app.arduinoObj)); %Start Drain
 
% %             t = datetime('now');
% %             internal_date_time_done_0 = strcat(string(hour(t)),string(minute(t)),string(int8(round(second(t)))));
% %             app.DateTimeString = strcat(string(month(t)),"/",string(day(t)), "/", string(year(t))," - ",string(hour(t)),":",string(minute(t)));
% %             app.fileName0 = sprintf("SavedData/imu0_%s.txt", internal_date_time_done_0);
% %             app.ExternalRotationListBox.Items(end+1) = {convertStringsToChars(app.DateTimeString)};
% %             app.ExternalRotationListBox.ItemsData(end+1) = internal_date_time_done_0;
% %             fprintf("SavedData/imu0_%s.txt\n", string(app.ExternalRotationListBox.ItemsData(2)));
           
        end

        % Button pushed function: StartExerciseButton
        function StartExerciseButtonPushed(app, event)

            if(app.MeasureInProg)
                app.StartExerciseButton.Text = "Start Exercise";
                app.StartExerciseButton.BackgroundColor = 'g';
                app.MeasureInProg = 0;
                app.SAVEButton.Enable = 1;
            else
                %readline(app.arduinoObj); %waiting for calibration start
                if (app.SelectanExerciseDropDown.Value == "Flexion" || app.SelectanExerciseDropDown.Value == "Abduction")
                    app.AbFlexImg.Visible = 1;
                    app.CalibArmLabel.Visible = 1;
                    pause(7);
                    app.AbFlexImg.Visible = 0;
                    app.CalibArmLabel.Visible = 0;                   
                elseif(app.SelectanExerciseDropDown.Value == "External Rotation")
                    app.ExtRotImg.Visible = 1;
                    app.CalibArmLabel.Visible = 1;
                    pause(7);
                    app.ExtRotImg.Visible = 0;
                    app.CalibArmLabel.Visible = 0;   
                else
                    %do nothing, not developed yet for more exercises
                end
                while(readline(app.arduinoObj) ~= "Waiting For Calibration Start")
                    %do nothing
                end
                %disp(readline(app.arduinoObj)); %Waiting For Calibration Start
                write(app.arduinoObj,'r',"char"); %start calibration
                app.CalibratingLabel.Visible = 1;
                disp(readline(app.arduinoObj)); %character received
                disp(readline(app.arduinoObj)); %start calibration
                disp(readline(app.arduinoObj)); %start exercise
                app.CalibratingLabel.Visible = 0;

                app.MeasureInProg = 1;
                
                t = datetime('now');
                app.internal_date_time_done = sprintf("%02d%02d%02d",hour(t),minute(t),round(second(t)));
                %app.internal_date_time_done = strcat(string(hour(t)),string(minute(t)),string(uint8(round(second(t)))));
                app.DateTimeString = sprintf("%02d/%02d/%02d - %02d:%02d", month(t),day(t),year(t),hour(t),minute(t));
                %app.DateTimeString = strcat(string(month(t)),"/",string(day(t)), "/", string(year(t))," - ",string(hour(t)),":",string(minute(t)));
                app.fileName0 = sprintf("SavedData/imu0_%s.txt", app.internal_date_time_done);
                app.fileName1 = sprintf("SavedData/imu0_%s.txt", app.internal_date_time_done);
                app.fileID = fopen(app.fileName0,'w');
                app.fileID2 = fopen(app.fileName1,'w');
                fprintf(app.fileID,'%s,%s,%s,%s,%s,%s,%s,%s,%s,%s,%s,%s\n','time','IMU#','roll', 'pitch', 'yaw', 'accelx','accely','accelz','compassx','compassy','compassz','maxangle');
                fprintf(app.fileID2,'%s,%s,%s,%s,%s,%s,%s,%s,%s,%s,%s,%s\n','time','IMU#','roll', 'pitch', 'yaw', 'accelx','accely','accelz','compassx','compassy','compassz','maxangle');

                app.StartExerciseButton.Text = "Stop Measurement";
                app.StartExerciseButton.BackgroundColor = 'r';
                tic
                configureCallback(app.arduinoObj,"terminator",@(src, event) readIMUData(app,src,event));
            end
        end

        % Value changed function: SelectanExerciseDropDown
        function SelectanExerciseDropDownValueChanged(app, event)
            
            value = app.SelectanExerciseDropDown.Value;

            app.StartExerciseButton.Enable = 1;
            app.resetmeasurementButton.Enable = 1;
            cleardata(app);

            if(value == "Abduction")
                app.ExerciseType = "Abduction";
                app.MainPlot = plot(app.MainAxes,NaN,NaN);
                [app.arduinoObj.UserData.RollPlot] = app.MainPlot;
                app.SubPlotLeft = plot(app.SubLeftAxes,NaN,NaN);
                [app.arduinoObj.UserData.YawPlot] = app.SubPlotLeft;
                app.SubPlotRight = plot(app.SubRightAxes,NaN,NaN);
                [app.arduinoObj.UserData.PitchPlot] = app.SubPlotRight;
            elseif(value == "Flexion")
                app.ExerciseType = "Flexion";
                app.SubPlotLeft = plot(app.SubLeftAxes,NaN,NaN);
                [app.arduinoObj.UserData.YawPlot] = app.SubPlotLeft;
                app.MainPlot = plot(app.MainAxes,NaN,NaN);
                [app.arduinoObj.UserData.RollPlot] = app.MainPlot;
                app.SubPlotRight = plot(app.SubRightAxes,NaN,NaN);
                [app.arduinoObj.UserData.PitchPlot] = app.SubPlotRight;
            elseif(value == "External Rotation")
                app.ExerciseType = "External Rotation";
                app.SubPlotRight = plot(app.SubRightAxes,NaN,NaN);
                [app.arduinoObj.UserData.RollPlot] = app.SubPlotRight;
                app.SubPlotLeft = plot(app.SubLeftAxes,NaN,NaN);
                [app.arduinoObj.UserData.PitchPlot] = app.SubPlotLeft;
                app.MainPlot = plot(app.MainAxes,NaN,NaN);
                [app.arduinoObj.UserData.YawPlot] = app.MainPlot;
            else
                app.StartExerciseButton.Enable = 0;
                app.resetmeasurementButton.Enable = 0;
            end
        end

        % Button pushed function: resetmeasurementButton
        function resetmeasurementButtonPushed(app, event)
            app.StartExerciseButton.Text = "Start Exercise";
            app.StartExerciseButton.BackgroundColor = 'g';
            if(app.MeasureInProg)
                app.MeasureInProg = 0;
            end
            cleardata(app);
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            savedata(app);
            app.SAVEButton.Enable = 0;
        end

        % Value changed function: AbductionListBox
        function AbductionListBoxValueChanged(app, event)
            value = app.AbductionListBox.Value;
            fileName_0 = sprintf("SavedData/imu0_%s.txt", string(value));
            %fileName1 = sprintf("SavedData/imu1_%s.txt", value);
            ExerciseData = readmatrix(fileName_0);
            ExerciseData(1,:) = []; %remove first row
            time = ExerciseData(:,1); %1st col = elapsed TIME
            roll = ExerciseData(:,3); 
            pitch = ExerciseData(:,4); 
            yaw = ExerciseData(:,5); 
            max_angles = ExerciseData(:,12);
            max_angle = max(max_angles);
            app.MaxAngleEditField_2.Value = max_angle;
            app.OvercompensationDetectedEditField_2.Value = "See Python";
            app.IncorrectFormDetectedEditField_2.Value = "See Python";
            plot(app.MainAxes2,time,roll); 
            plot(app.SubRightAxes2,time,pitch);
            plot(app.SubLeftAxes2,time,yaw);


        end

        % Value changed function: FlexionListBox
        function FlexionListBoxValueChanged(app, event)
            value = app.FlexionListBox.Value;
            fileName_0 = sprintf("SavedData/imu0_%s.txt", string(value));
            %fileName1 = sprintf("SavedData/imu1_%s.txt", value);
            ExerciseData = readmatrix(fileName_0);
            ExerciseData(1,:) = []; %remove first row
            time = ExerciseData(:,1); %1st col = elapsed TIME
            roll = ExerciseData(:,3); 
            pitch = ExerciseData(:,4); 
            yaw = ExerciseData(:,5); 
            max_angles = ExerciseData(:,12);
            max_angle = max(max_angles);
            app.MaxAngleEditField_2.Value = max_angle;
            app.OvercompensationDetectedEditField_2.Value = "See Python";
            app.IncorrectFormDetectedEditField_2.Value = "See Python";
            plot(app.MainAxes2,time,roll); 
            plot(app.SubRightAxes2,time,pitch);
            plot(app.SubLeftAxes2,time,yaw);
            
        end

        % Value changed function: ExternalRotationListBox
        function ExternalRotationListBoxValueChanged(app, event)
            value = app.ExternalRotationListBox.Value;
            fileName_0 = sprintf("SavedData/imu0_%s.txt", string(value));
            %fileName1 = sprintf("SavedData/imu1_%s.txt", value);
            ExerciseData = readmatrix(fileName_0);
            ExerciseData(1,:) = []; %remove first row
            time = ExerciseData(:,1); %1st col = elapsed TIME
            roll = ExerciseData(:,3); 
            pitch = ExerciseData(:,4); 
            yaw = ExerciseData(:,5); 
            max_angles = ExerciseData(:,12);
            max_angle = max(max_angles);
            app.MaxAngleEditField_2.Value = max_angle;
            app.OvercompensationDetectedEditField_2.Value = "See Python";
            app.IncorrectFormDetectedEditField_2.Value = "See Python";
            plot(app.MainAxes2,time,yaw); 
            plot(app.SubRightAxes2,time,roll);
            plot(app.SubLeftAxes2,time,pitch);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RoMMeasurementTab = uitab(app.TabGroup);
            app.RoMMeasurementTab.Title = 'RoM Measurement';
            app.MainAxes = uiaxes(app.RoMMeasurementTab);
            title(app.MainAxes, 'Primary Rotation Angle')
            xlabel(app.MainAxes, 'Elapsed Time [s]')
            ylabel(app.MainAxes, 'Displacement Angle')
            zlabel(app.MainAxes, 'Z')
            app.MainAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.MainAxes.XTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '10'; '0.1'; '0.2'; '0.30000000000000004'; '0.4'; '0.5'; '0.6000000000000001'; '0.7000000000000001'; '0.8'; '0.9'; '1'};
            app.MainAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.SubLeftAxes = uiaxes(app.RoMMeasurementTab);
            title(app.SubLeftAxes, 'Auxillary Rotation Angle 1 - Off Center')
            zlabel(app.SubLeftAxes, 'Z')
            app.SubRightAxes = uiaxes(app.RoMMeasurementTab);
            title(app.SubRightAxes, 'Auxillary Rotation Angle 2 - Twist')
            app.SelectanExerciseDropDown = uidropdown(app.RoMMeasurementTab);
            app.SelectanExerciseDropDown.Items = {'Select An Exercise...', 'Flexion', 'Abduction', 'External Rotation', 'Other'};
            app.SelectanExerciseDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectanExerciseDropDownValueChanged, true);
            app.SelectanExerciseDropDown.Value = 'Select An Exercise...';
            app.Image = uiimage(app.RoMMeasurementTab);
            app.Image.ImageSource = 'CromaTech Logo.png';
            app.resetmeasurementButton = uibutton(app.RoMMeasurementTab, 'push');
            app.resetmeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @resetmeasurementButtonPushed, true);
            app.resetmeasurementButton.Enable = 'off';
            app.resetmeasurementButton.Text = 'reset measurement';
            app.StartExerciseButton = uibutton(app.RoMMeasurementTab, 'push');
            app.StartExerciseButton.ButtonPushedFcn = createCallbackFcn(app, @StartExerciseButtonPushed, true);
            app.StartExerciseButton.Enable = 'off';
            app.StartExerciseButton.Text = 'Start Exercise';
            app.CurrentAngleEditFieldLabel = uilabel(app.RoMMeasurementTab);
            app.CurrentAngleEditFieldLabel.Text = 'Current Angle';
            app.CurrentAngleEditField = uieditfield(app.RoMMeasurementTab, 'text');
            app.MaxAngleEditFieldLabel = uilabel(app.RoMMeasurementTab);
            app.MaxAngleEditFieldLabel.Text = 'Max Angle';
            app.MaxAngleEditField = uieditfield(app.RoMMeasurementTab, 'text');
            app.OvercompensationDetectedLabel = uilabel(app.RoMMeasurementTab);
            app.OvercompensationDetectedLabel.Text = {'Overcompensation'; 'Detected'};
            app.OvercompensationDetectedEditField = uieditfield(app.RoMMeasurementTab, 'text');
            app.OverComp = uilabel(app.RoMMeasurementTab);
            app.OverComp.Text = 'NO';
            app.IncorrectFormDetectedEditFieldLabel = uilabel(app.RoMMeasurementTab);
            app.IncorrectFormDetectedEditFieldLabel.Text = {'Incorrect Form '; 'Detected'};
            app.IncorrectFormDetectedEditField = uieditfield(app.RoMMeasurementTab, 'text');
            app.IncForm = uilabel(app.RoMMeasurementTab);
            app.IncForm.Text = 'NO';
            app.SAVEButton = uibutton(app.RoMMeasurementTab, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.CalibratingLabel = uilabel(app.RoMMeasurementTab);
            app.CalibratingLabel.Visible = 'off';
            app.CalibratingLabel.Text = {'Calibrating...'; ''};
            app.CalibArmLabel = uilabel(app.RoMMeasurementTab);
            app.CalibArmLabel.Visible = 'off';
            app.CalibArmLabel.Text = {'  Place and hold your '; '  arm in the following '; '  position:'};
            app.AbFlexImg = uiimage(app.RoMMeasurementTab);
            app.AbFlexImg.Visible = 'off';
            app.AbFlexImg.ImageSource = fullfile(pathToMLAPP, 'ab_flex.png');
            app.ExtRotImg = uiimage(app.RoMMeasurementTab);
            app.ExtRotImg.Visible = 'off';
            app.ExtRotImg.ImageSource = fullfile(pathToMLAPP, 'externalrot.png');
            app.ViewPreviousExercisesTab = uitab(app.TabGroup);
            app.ViewPreviousExercisesTab.Title = 'View Previous Exercises';
            app.MainAxes2 = uiaxes(app.ViewPreviousExercisesTab);
            title(app.MainAxes2, 'Primary Rotation Angle')
            xlabel(app.MainAxes2, 'Elapsed Time [s]')
            ylabel(app.MainAxes2, 'Displacement Angle')
            zlabel(app.MainAxes2, 'Z')
            app.SubLeftAxes2 = uiaxes(app.ViewPreviousExercisesTab);
            title(app.SubLeftAxes2, 'Auxillary Rotation Angle 1 - Off-Center')
            xlabel(app.SubLeftAxes2, 'Elapsed Time [s]')
            ylabel(app.SubLeftAxes2, 'Displacement Angle')
            zlabel(app.SubLeftAxes2, 'Z')
            app.SubRightAxes2 = uiaxes(app.ViewPreviousExercisesTab);
            title(app.SubRightAxes2, 'Auxillary Rotation Angle 2 - Twist')
            xlabel(app.SubRightAxes2, 'Elapsed Time [s]')
            ylabel(app.SubRightAxes2, 'Displacement Angle')
            zlabel(app.SubRightAxes2, 'Z')
            app.AbductionListBoxLabel = uilabel(app.ViewPreviousExercisesTab);
            app.AbductionListBoxLabel.Text = 'Abduction';
            app.AbductionListBox = uilistbox(app.ViewPreviousExercisesTab);
            app.AbductionListBox.Items = {};
            app.AbductionListBox.ValueChangedFcn = createCallbackFcn(app, @AbductionListBoxValueChanged, true);
            app.AbductionListBox.Value = {};
            app.FlexionListBoxLabel = uilabel(app.ViewPreviousExercisesTab);
            app.FlexionListBoxLabel.Text = 'Flexion';
            app.FlexionListBox = uilistbox(app.ViewPreviousExercisesTab);
            app.FlexionListBox.Items = {};
            app.FlexionListBox.ValueChangedFcn = createCallbackFcn(app, @FlexionListBoxValueChanged, true);
            app.FlexionListBox.Value = {};
            app.ExternalRotationListBoxLabel = uilabel(app.ViewPreviousExercisesTab);
            app.ExternalRotationListBoxLabel.Text = {'External'; 'Rotation'};
            app.ExternalRotationListBox = uilistbox(app.ViewPreviousExercisesTab);
            app.ExternalRotationListBox.Items = {};
            app.ExternalRotationListBox.ValueChangedFcn = createCallbackFcn(app, @ExternalRotationListBoxValueChanged, true);
            app.ExternalRotationListBox.Value = {};
            app.Image_2 = uiimage(app.ViewPreviousExercisesTab);
            app.Image_2.ImageSource = 'CromaTech Logo.png';
            app.OvercompensationDetectedEditField_2Label = uilabel(app.ViewPreviousExercisesTab);
            app.OvercompensationDetectedEditField_2Label.Text = {'Overcompensation'; 'Detected'};
            app.OvercompensationDetectedEditField_2 = uieditfield(app.ViewPreviousExercisesTab, 'text');
            app.IncorrectFormDetectedLabel = uilabel(app.ViewPreviousExercisesTab);
            app.IncorrectFormDetectedLabel.Text = {'Incorrect Form'; 'Detected'};
            app.IncorrectFormDetectedEditField_2 = uieditfield(app.ViewPreviousExercisesTab, 'text');
            app.MaxAngleEditField_2Label = uilabel(app.ViewPreviousExercisesTab);
            app.MaxAngleEditField_2Label.Text = 'Max Angle';
            app.MaxAngleEditField_2 = uieditfield(app.ViewPreviousExercisesTab, 'numeric');
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TESTAPP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end